classdef martingala_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figura      matlab.ui.Figure
        aResults    matlab.ui.control.UIAxes
        tNumero     matlab.ui.control.Label
        eNumero     matlab.ui.control.EditField
        tBase       matlab.ui.control.Label
        eBase       matlab.ui.control.EditField
        cMaxima     matlab.ui.control.CheckBox
        eMaxima     matlab.ui.control.EditField
        pReiniciar  matlab.ui.control.Button
        pSimular    matlab.ui.control.Button
        pImagen     matlab.ui.control.Button
        pExcel      matlab.ui.control.Button
    end

    
    properties (Access = private)
        results 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function martingala_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to martingala (see VARARGIN)
%             
%             % Choose default command line output for martingala
%             app.output = hObject;
%             
%             app.results = [];
%             pReiniciar_Callback(app.pReiniciar , eventdata, handles);
%             
%             % Update handles structure
%             guidata(hObject, handles);
            app.pReiniciar_Callback();
        end

        % Value changed function: cMaxima
        function cMaxima_Callback(app, event)
            % hObject    handle to cMaxima (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cMaxima
            
            if event.Value
                app.eMaxima.Enable = 'On';
            else
                app.eMaxima.Enable = 'Off';
            end
        end

        % Button pushed function: pExcel
        function pExcel_Callback(app, event)
            % hObject    handle to pExcel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [filename, pathname] = uiputfile( ...
                   {'*.xls','Archivo Excel (*.xls)'}, ...
                   'Selecciona un archivo', 'Martingala.xls');
            
            if ischar(filename)
                xlswrite(fullfile(pathname, filename), app.results);
            end
        end

        % Button pushed function: pImagen
        function pImagen_Callback(app, event)
            % hObject    handle to pImagen (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [filename, pathname] = uiputfile( ...
                   {'*.jpg','Imágenes JPG (*.jpg)'}, ...
                   'Selecciona un archivo', 'Martingala.jpg');
            
            if ischar(filename)
                h = figure;
                h.Visible = 'off';
                x = app.aResults.XAxis.Parent.Children.XData;
                y = app.aResults.XAxis.Parent.Children.YData;
                
                plot(x,y)
                h.Children.XLabel.String = app.aResults.XLabel.String;
                h.Children.YLabel.String = app.aResults.YLabel.String;
                h.Children.Title.String = app.aResults.Title.String;
                
                saveas(h, fullfile(pathname, filename),'jpg');
                delete(h);
            end
        end

        % Button pushed function: pReiniciar
        function pReiniciar_Callback(app, event)
            % hObject    handle to pReiniciar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            app.eNumero.Value= '10000';
            app.eBase.Value = '10';
            app.cMaxima.Value = false;
            app.eMaxima.Value = '';
            app.eMaxima.Enable = 'Off';
            
            app.pImagen.Enable = 'Off';
            app.pExcel.Enable = 'Off';
            
            cla(app.aResults);
        end

        % Button pushed function: pSimular
        function pSimular_Callback(app, event)
            % hObject    handle to pSimular (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            g = str2double(app.eNumero.Value);
            b = str2double(app.eBase.Value);
            
            if app.cMaxima.Value
                max_bet = str2double(app.eMaxima.Value);
            else
                max_bet = Inf;
            end
            
            streak = 0;
            result = zeros(1, g);
            
            for i = 1:g
                % Apuesta en base a la racha
                bet = b * 2^(streak - 1);
            
                % Limitar la apuesta a la máxima permitida
                if bet > max_bet
                    bet = max_bet;
                end
            
                % Simular una partida
                if rand() < 18/37
                    streak = 0;
                    result(i) = bet;
                else
                    streak = streak + 1;
                    result(i) = -bet;
                end
            end
            
            plot(app.aResults, cumsum(result))
            xlabel(app.aResults, 'Juegos');
            ylabel(app.aResults, 'Beneficio acumulado');
            
            if isinf(max_bet)
                title(app.aResults, 'No hay apuesta máxima');
            else
                title(app.aResults, sprintf('Apuesta máxima %d', max_bet));
            end
            
            app.pImagen.Enable = 'On';
            app.pExcel.Enable = 'On';
            
            app.results = [result; cumsum(result)]';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figura = uifigure('Visible', 'off');
            app.figura.Name = 'Simulador Martingala';
            app.aResults = uiaxes(app.figura);
            app.tNumero = uilabel(app.figura);
            app.tNumero.Text = 'Número de apuestas';
            app.eNumero = uieditfield(app.figura, 'text');
            app.tBase = uilabel(app.figura);
            app.tBase.Text = 'Apuesta base';
            app.eBase = uieditfield(app.figura, 'text');
            app.cMaxima = uicheckbox(app.figura);
            app.cMaxima.ValueChangedFcn = createCallbackFcn(app, @cMaxima_Callback, true);
            app.cMaxima.Text = 'Apuesta máxima permitida';
            app.eMaxima = uieditfield(app.figura, 'text');
            app.pReiniciar = uibutton(app.figura, 'push');
            app.pReiniciar.ButtonPushedFcn = createCallbackFcn(app, @pReiniciar_Callback, true);
            app.pReiniciar.Text = 'Reiniciar';
            app.pSimular = uibutton(app.figura, 'push');
            app.pSimular.ButtonPushedFcn = createCallbackFcn(app, @pSimular_Callback, true);
            app.pSimular.Text = 'Simular';
            app.pImagen = uibutton(app.figura, 'push');
            app.pImagen.ButtonPushedFcn = createCallbackFcn(app, @pImagen_Callback, true);
            app.pImagen.Text = 'Exportar imagen';
            app.pExcel = uibutton(app.figura, 'push');
            app.pExcel.ButtonPushedFcn = createCallbackFcn(app, @pExcel_Callback, true);
            app.pExcel.Text = 'Exportar Excel';
            % Show the figure after all components are created
            app.figura.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = martingala_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figura)

            % Execute the startup function
            runStartupFcn(app, @martingala_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figura)
        end
    end
end